classdef mels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        ExplanationLabel              matlab.ui.control.Label
        ADiscreteTimeCalculatorLabel  matlab.ui.control.Label
        StabilityDetermination        matlab.ui.control.Button
        InvarianceDetermination       matlab.ui.control.Button
        LinearityDetermination        matlab.ui.control.Button
        CausalityDetermination        matlab.ui.control.Button
        EnergyDetermination           matlab.ui.control.Button
        StaticDetermination           matlab.ui.control.Button
        Output                        matlab.ui.control.TextArea
        ClassificationTextAreaLabel   matlab.ui.control.Label
        InputBox                      matlab.ui.control.TextArea
        SysteminputLabel              matlab.ui.control.Label
    end

        
        methods (Access = public)
            
         function signal = getInputSignal(app)
    try
        % Convert input to string and trim spaces
        inputSignal = strtrim(strjoin(app.InputBox.Value, ' '));

        % Ensure input is not empty
        if isempty(inputSignal)
            app.Output.Value = 'Error: Empty input';
            signal = [];
            return;
        end

        % Check if input follows 'y(n) = expression' format
        inputPattern = '(y|x)\(n\)\s*=\s*(.*)'; % Regex to capture expression
        tokens = regexp(inputSignal, inputPattern, 'tokens');

        if ~isempty(tokens)
            inputSignal = strtrim(tokens{1}{2}); % Extract the expression
        else
            app.Output.Value = 'Error: Invalid input format. Use "y(n) =" or "x(n) ="';
            signal = [];
            return;
        end

        % Define symbolic variables properly
        syms x(n) n  % Declare x(n) as a symbolic function of n

        % Convert input expression to symbolic
        inputSignal = strrep(inputSignal, '^', '.^'); % Ensure element-wise exponentiation
        inputSignal = strrep(inputSignal, '*', '.*'); % Ensure element-wise multiplication
        inputSignal = strrep(inputSignal, '/', './'); % Ensure element-wise division
        signal = str2sym(inputSignal); % Convert to symbolic expression
        
    catch ME
        app.Output.Value = ['Error: ', ME.message];
        signal = [];
    end
end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StaticDetermination
        function StaticDeterminationPushed(app, event)
                signal = app.getInputSignal();
    if isempty(signal)
        return;
    end
    
    expr = char(signal);
    % Extract the argument inside x()
    tokens = regexp(expr, 'x\((.*?)\)', 'tokens');
    
    if isempty(tokens)
        app.Output.Value = 'Static';
        app.ExplanationLabel.Text = 'Static: Output does not depend on any input (no x terms).';
        return;
    end
    
    x_arg = strtrim(tokens{1}{1}); % Get the content inside x()
    if strcmp(x_arg, 'n')
        app.Output.Value = 'Static';
        app.ExplanationLabel.Text = 'Static: Output depends only on current input x(n).';
    else
        app.Output.Value = 'Dynamic'; % e.g., x(-n), x(n+1)
        app.ExplanationLabel.Text = 'Dynamic: Output depends on past/future inputs (e.g., x(n±k)).';
    end
        end

        % Button pushed function: EnergyDetermination
        function EnergyDeterminationPushed(app, event)
signal = app.getInputSignal();
    if isempty(signal)
        app.ExplanationLabel.Text = 'Error: Empty input';
        return;
    end

    syms n integer;
    syms x(n);
    
    try
        % Compute impulse response h(n)
        delta(n) = kroneckerDelta(n, 0);
        h(n) = subs(signal, x(n), delta(n));
        
        % Check if h(n) is a sum of kroneckerDelta terms (FIR)
        isFIR = false;
        tol = 1e-6;
        
        % Attempt symbolic analysis for FIR detection
        h_expr = h(n);
        if has(h_expr, 'kroneckerDelta')
            % Extract all kroneckerDelta terms in h(n)
            delta_terms = children(h_expr);
            isFIR = true;
            for term = delta_terms
                % Check if the term is a kroneckerDelta
                if ~has(term, 'kroneckerDelta')
                    isFIR = false;
                    break;
                end
            end
        end
        
        if ~isFIR
            % Fallback: Numerical check for FIR (expanded range)
            N_check = 5000; % Test over n = -5000 to 5000
            window = 100;  % FIR if non-zero only within |n| ≤ 100
            isFIR = true;
            for k = -N_check:N_check
                h_k = double(subs(h(n), n, k));
                if abs(h_k) > tol && abs(k) > window
                    isFIR = false;
                    break;
                end
            end
        end
        
        if isFIR
            % Finite energy calculation
            energy = 0;
            for k = -1000:1000
                h_k = double(subs(h(n), n, k));
                energy = energy + abs(h_k)^2;
            end
            app.Output.Value = 'Energy Signal';
            app.ExplanationLabel.Text = 'Finite energy (FIR system)';
        else
            % Average power calculation for IIR
            N = 10000;
            power = 0;
            for k = -N:N
                h_k = double(subs(h(n), n, k));
                power = power + abs(h_k)^2;
            end
            power = power / (2*N + 1);
            
            if isfinite(power)
                app.Output.Value = 'Power Signal';
                app.ExplanationLabel.Text = sprintf('Average power = %.4f', power);
            else
                app.Output.Value = 'Neither';
                app.ExplanationLabel.Text = 'Unbounded energy/power';
            end
        end
        
    catch ME
        app.Output.Value = ['Error: ', ME.message];
        app.ExplanationLabel.Text = ['Energy Test Error: ', ME.message];
    end
        end

        % Button pushed function: CausalityDetermination
        function CausalityDeterminationPushed(app, event)
             signal = app.getInputSignal();
    if isempty(signal)
        return;
    end
    
    expr = char(signal);
    disp(['Processed expression: ', expr]); % Debugging line

    % Enhanced regex to detect 'x(n+k)', even when preceded by 'y(n) = '
    antiCausalTerms = regexp(expr, 'x\s*\(\s*n\s*\+\s*\d+\s*\)', 'match');

    if ~isempty(antiCausalTerms)
        app.Output.Value = 'Anti-Causal'; % Depends on future inputs
        app.ExplanationLabel.Text = 'Anti-Causal: Depends on future inputs (e.g., x(n+k)).';
    else
        app.Output.Value = 'Causal';
        app.ExplanationLabel.Text = 'Causal: Depends only on present/past inputs.';
    end
        end

        % Button pushed function: LinearityDetermination
        function LinearityDeterminationPushed(app, event)
           signal = app.getInputSignal();
    if isempty(signal)
        return;
    end
    
    syms n integer;
    syms x(n) x1(n) x2(n) a b;
    
    try
        % Replace x(·) with a*x1(·) + b*x2(·)
        T_ax1_bx2 = subs(signal, x, a*x1 + b*x2);
        T_x1 = subs(signal, x, x1);
        T_x2 = subs(signal, x, x2);
        aT_plus_bT = a*T_x1 + b*T_x2;
        
        % Check equality
        isLinear = logical(isAlways(T_ax1_bx2 == aT_plus_bT));
        
        % Use native if-else
        if isLinear
            app.Output.Value = 'Linear';
            app.ExplanationLabel.Text = 'Linear: Satisfies superposition principle.';
            app.ExplanationLabel.Text = 'Non-Linear: Fails superposition.';
        else
            app.Output.Value = 'Non-Linear';

        end
        
    catch ME
        app.Output.Value = ['Error: ', ME.message];
    end
        end

        % Button pushed function: InvarianceDetermination
        function InvarianceDeterminationPushed(app, event)
             signal = app.getInputSignal();
    if isempty(signal)
        return;
    end
    
    syms n k integer;
    syms x(n);
    
    try
        % Compute T[x(n - k)] and y(n - k)
        x_shift(n) = x(n - k);
        T_x_shift = subs(signal, x(n), x_shift); % T[x(n - k)]
        y_shifted = subs(signal, n, n - k);      % y(n - k)
        
        % Check if T[x(n - k)] == y(n - k)
        isInvariant = logical(isAlways(T_x_shift == y_shifted, 'Unknown', 'false'));
        
        if isInvariant
            app.Output.Value = 'Time Invariant';
            app.ExplanationLabel.Text = 'Time Invariant: Output shifts with input delay.';
        else
            app.Output.Value = 'Time Variant'; % Correctly flags x(-n) as variant
            app.ExplanationLabel.Text = 'Time Variant: Output changes with input delay.';
        end
        
    catch ME
        app.Output.Value = ['Error: ', ME.message];
    end
        end

        % Button pushed function: StabilityDetermination
        function StabilityDeterminationPushed(app, event)
           signal = app.getInputSignal();
    if isempty(signal)
        return;
    end
    
    % Check for unstable terms (e.g., n*x(n))
    expr = char(signal);
    unstableTerms = regexp(expr, 'n\*x\(n\)|n\.\*x\(n\)|n\^|n\.\^', 'match');
    
    if ~isempty(unstableTerms)
        app.Output.Value = 'Unstable';
    else
        % Test with bounded input (e.g., x(n) = 1)
        try
            N = 1000;
            x_test = ones(1, N);
            n_values = 0:N-1;
            y = double(subs(signal, {n, x(n)}, {n_values, x_test}));
            if all(abs(y) < Inf)
                app.Output.Value = 'Stable'; % Correct for x(-n)
                app.ExplanationLabel.Text = 'Stable: Bounded Input leads to Bounded Output.';
            else
                app.Output.Value = 'Unstable';
                app.ExplanationLabel.Text = 'Unstable: Contains terms like n*x(n) leading to unbounded output.';
            end
        catch
            app.Output.Value = 'Stable (Assumed)';
            app.ExplanationLabel.Text = 'Stable: Assumed due to no detected instability terms.';
        end
    end
        end

        % Callback function
        function InputTextAreaValueChanged(app, event)
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SysteminputLabel = uilabel(app.UIFigure);
            app.SysteminputLabel.Text = 'System input';
            app.InputBox = uitextarea(app.UIFigure);
            app.ClassificationTextAreaLabel = uilabel(app.UIFigure);
            app.ClassificationTextAreaLabel.Text = 'Classification';
            app.Output = uitextarea(app.UIFigure);
            app.StaticDetermination = uibutton(app.UIFigure, 'push');
            app.StaticDetermination.ButtonPushedFcn = createCallbackFcn(app, @StaticDeterminationPushed, true);
            app.StaticDetermination.Text = 'Static test';
            app.EnergyDetermination = uibutton(app.UIFigure, 'push');
            app.EnergyDetermination.ButtonPushedFcn = createCallbackFcn(app, @EnergyDeterminationPushed, true);
            app.EnergyDetermination.Text = 'Energy test';
            app.CausalityDetermination = uibutton(app.UIFigure, 'push');
            app.CausalityDetermination.ButtonPushedFcn = createCallbackFcn(app, @CausalityDeterminationPushed, true);
            app.CausalityDetermination.Text = 'Causality test';
            app.LinearityDetermination = uibutton(app.UIFigure, 'push');
            app.LinearityDetermination.ButtonPushedFcn = createCallbackFcn(app, @LinearityDeterminationPushed, true);
            app.LinearityDetermination.Text = 'Linearity test';
            app.InvarianceDetermination = uibutton(app.UIFigure, 'push');
            app.InvarianceDetermination.ButtonPushedFcn = createCallbackFcn(app, @InvarianceDeterminationPushed, true);
            app.InvarianceDetermination.Text = 'Invariance test';
            app.StabilityDetermination = uibutton(app.UIFigure, 'push');
            app.StabilityDetermination.ButtonPushedFcn = createCallbackFcn(app, @StabilityDeterminationPushed, true);
            app.StabilityDetermination.Text = 'Stability test';
            app.ADiscreteTimeCalculatorLabel = uilabel(app.UIFigure);
            app.ADiscreteTimeCalculatorLabel.Text = 'A Discrete-Time Calculator';
            app.ExplanationLabel = uilabel(app.UIFigure);
            app.ExplanationLabel.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end